classdef CRC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRC_app                        matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        GeneratorTab                   matlab.ui.container.Tab
        gxTextArea_2Label              matlab.ui.control.Label
        gxTextAreaLabel                matlab.ui.control.Label
        gxTextArea_2                   matlab.ui.control.TextArea
        PresetsPanel                   matlab.ui.container.Panel
        CRC5USBTokenButton             matlab.ui.control.Button
        CRC16USBDataButton             matlab.ui.control.Button
        CRC8ATMHECButton               matlab.ui.control.Button
        CRC10Button                    matlab.ui.control.Button
        CRC6X25Button                  matlab.ui.control.Button
        CRC32EthernetButton            matlab.ui.control.Button
        gxTextArea                     matlab.ui.control.TextArea
        GeneratorpolynomialPanel       matlab.ui.container.Panel
        Button                         matlab.ui.control.StateButton
        ApplyButton                    matlab.ui.control.Button
        x32Button                      matlab.ui.control.StateButton
        x31Button                      matlab.ui.control.StateButton
        x30Button                      matlab.ui.control.StateButton
        x29Button                      matlab.ui.control.StateButton
        x28Button                      matlab.ui.control.StateButton
        x27Button                      matlab.ui.control.StateButton
        x26Button                      matlab.ui.control.StateButton
        x25Button                      matlab.ui.control.StateButton
        x24Button                      matlab.ui.control.StateButton
        x23Button                      matlab.ui.control.StateButton
        x22Button                      matlab.ui.control.StateButton
        x21Button                      matlab.ui.control.StateButton
        x20Button                      matlab.ui.control.StateButton
        x19Button                      matlab.ui.control.StateButton
        x18Button                      matlab.ui.control.StateButton
        x17Button                      matlab.ui.control.StateButton
        x16Button                      matlab.ui.control.StateButton
        x15Button                      matlab.ui.control.StateButton
        x14Button                      matlab.ui.control.StateButton
        x13Button                      matlab.ui.control.StateButton
        x12Button                      matlab.ui.control.StateButton
        x11Button                      matlab.ui.control.StateButton
        x10Button                      matlab.ui.control.StateButton
        x9Button                       matlab.ui.control.StateButton
        x8Button                       matlab.ui.control.StateButton
        x7Button                       matlab.ui.control.StateButton
        x6Button                       matlab.ui.control.StateButton
        x5Button                       matlab.ui.control.StateButton
        x4Button                       matlab.ui.control.StateButton
        x3Button                       matlab.ui.control.StateButton
        x2Button                       matlab.ui.control.StateButton
        xButton                        matlab.ui.control.StateButton
        EncodeTab                      matlab.ui.container.Tab
        EncodedmessageTextAreaLabel    matlab.ui.control.Label
        RemainderTextAreaLabel         matlab.ui.control.Label
        MessageLabel                   matlab.ui.control.Label
        EncodedmessageTextArea         matlab.ui.control.TextArea
        RemainderTextArea              matlab.ui.control.TextArea
        EncodeButton                   matlab.ui.control.Button
        MessageEditField               matlab.ui.control.EditField
        mxTextArea                     matlab.ui.control.TextArea
        DecodeTab                      matlab.ui.container.Tab
        ReceivedMessageLabel           matlab.ui.control.Label
        RemovechecksumButton           matlab.ui.control.Button
        DecodedTextArea_2              matlab.ui.control.TextArea
        EncodedmessageTextArea_2Label_2  matlab.ui.control.Label
        EncodedmessageTextArea_2Label  matlab.ui.control.Label
        DecodedTextArea                matlab.ui.control.TextArea
        CheckButton                    matlab.ui.control.Button
        EncodedmessageTextArea_2       matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        x % gen polynom koeficient cell array
        msg % zprava na zakodovani
        msg_bin % zprava v 01
        genpol % generator polynom MSB
        gpb % generator polynom LSB
        mx_result % zabezpecena zprava
        mx_remainder % zbytek po bitových operacích
        mx_decoded % zkontrolovana zprava
        chyba % vektor poctu chyb
        format % format pro cell2gui
        expected_length % cell array s ocekavanymi delkami zprav
        actual_length % cell array se skutecnymi delkami zprav
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            evalin('base', 'clear all');
            %prealokace gen. polynomial cell array
            for l = 1:33
                app.x{l} = 0;
            end
        end

        % Value changed function: xButton
        function xButtonValueChanged(app, event)
            app.x{2} = double(app.xButton.Value);
        end

        % Value changed function: x2Button
        function x2ButtonValueChanged(app, event)
            app.x{3} = double(app.x2Button.Value);
        end

        % Value changed function: x3Button
        function x3ButtonValueChanged(app, event)
           app.x{4} = double(app.x3Button.Value);
        end

        % Value changed function: x4Button
        function x4ButtonValueChanged(app, event)
            app.x{5} = double(app.x4Button.Value);
        end

        % Value changed function: x5Button
        function x5ButtonValueChanged(app, event)
            app.x{6} = double(app.x5Button.Value);
        end

        % Value changed function: x6Button
        function x6ButtonValueChanged(app, event)
            app.x{7} = double(app.x6Button.Value);
        end

        % Value changed function: x7Button
        function x7ButtonValueChanged(app, event)
            app.x{8} = double(app.x7Button.Value);
        end

        % Value changed function: x8Button
        function x8ButtonValueChanged(app, event)
            app.x{9} = double(app.x8Button.Value);
        end

        % Value changed function: x9Button
        function x9ButtonValueChanged(app, event)
           app.x{10} = double(app.x9Button.Value);
        end

        % Value changed function: x10Button
        function x10ButtonValueChanged(app, event)
            app.x{11} = double(app.x10Button.Value);
        end

        % Value changed function: x11Button
        function x11ButtonValueChanged(app, event)
            app.x{12} = double(app.x11Button.Value);
        end

        % Value changed function: x12Button
        function x12ButtonValueChanged(app, event)
            app.x{13} = double(app.x12Button.Value);
        end

        % Value changed function: x13Button
        function x13ButtonValueChanged(app, event)
            app.x{14} = double(app.x13Button.Value);
        end

        % Value changed function: x14Button
        function x14ButtonValueChanged(app, event)
            app.x{15} = double(app.x14Button.Value);
        end

        % Value changed function: x15Button
        function x15ButtonValueChanged(app, event)
            app.x{16} = double(app.x15Button.Value);
        end

        % Value changed function: x16Button
        function x16ButtonValueChanged(app, event)
            app.x{17} = double(app.x16Button.Value);
        end

        % Value changed function: x17Button
        function x17ButtonValueChanged(app, event)
            app.x{18} = double(app.x17Button.Value);
        end

        % Value changed function: x18Button
        function x18ButtonValueChanged(app, event)
            app.x{19} = double(app.x18Button.Value);
        end

        % Value changed function: x19Button
        function x19ButtonValueChanged(app, event)
            app.x{20} = double(app.x19Button.Value);
        end

        % Value changed function: x20Button
        function x20ButtonValueChanged(app, event)
            app.x{21} = double(app.x20Button.Value);
        end

        % Value changed function: x21Button
        function x21ButtonValueChanged(app, event)
            app.x{22} = double(app.x21Button.Value);
        end

        % Value changed function: x22Button
        function x22ButtonValueChanged(app, event)
            app.x{23} = double(app.x22Button.Value);
        end

        % Value changed function: x23Button
        function x23ButtonValueChanged(app, event)
            app.x{24} = double(app.x23Button.Value);
        end

        % Value changed function: x24Button
        function x24ButtonValueChanged(app, event)
            app.x{25} = double(app.x24Button.Value);
        end

        % Value changed function: x25Button
        function x25ButtonValueChanged(app, event)
            app.x{26} = double(app.x25Button.Value);
        end

        % Value changed function: x26Button
        function x26ButtonValueChanged(app, event)
            app.x{27} = double(app.x26Button.Value);
        end

        % Value changed function: x27Button
        function x27ButtonValueChanged(app, event)
            app.x{28} = double(app.x27Button.Value);
        end

        % Value changed function: x28Button
        function x28ButtonValueChanged(app, event)
            app.x{29} = double(app.x28Button.Value);
        end

        % Value changed function: x29Button
        function x29ButtonValueChanged(app, event)
            app.x{30} = double(app.x29Button.Value);
        end

        % Value changed function: x30Button
        function x30ButtonValueChanged(app, event)
            app.x{31} = double(app.x30Button.Value);
        end

        % Value changed function: x31Button
        function x31ButtonValueChanged(app, event)
            app.x{32} = double(app.x31Button.Value);
        end

        % Value changed function: x32Button
        function x32ButtonValueChanged(app, event)
            app.x{33} = double(app.x32Button.Value);
        end

        % Value changed function: MessageEditField
        function MessageEditFieldValueChanged(app, event)
            value = app.MessageEditField.Value;
            app.msg = value;
            app.msg_bin = msgtobin(app.msg);
            app.mxTextArea.Value = cell2gui(app.msg_bin,'%s');
            set(app.EncodeButton, 'Enable', 'on');
            
%             assignin('base','msg',app.msg) %debug
%             assignin('base','msg_bin',app.msg_bin) %debug
        end

        % Value changed function: mxTextArea
        function mxTextAreaValueChanged(app, event)
%             value = app.mxTextArea.Value;
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)

         [app.genpol, app.gpb] = guitogenpol(app.x);
         app.gxTextArea.Value = genpoltostr(app.genpol);
         app.gxTextArea_2.Value = num2str(app.gpb);
         if any(app.genpol)
         set(app.MessageEditField, 'Enable', 'on');
         elseif ~any(app.genpol)
             uialert(app.CRC_app,"It is of upmost inportance for the generator polynomial to be defined if we are to get anywhere!", 'Generator Polynomial Empty');
         end
%          assignin('base','gpb',app.gpb) %debug
%          assignin('base','x',app.x) %debug
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            app.x{1} = double(app.Button.Value);           
        end

        % Value changed function: gxTextArea
        function gxTextAreaValueChanged(app, event)
%             value = app.gxTextArea.Value;
        end

        % Button pushed function: EncodeButton
        function EncodeButtonPushed(app, event)
            if any(app.genpol)
                if isempty(app.MessageEditField.Value)
                    uialert(app.CRC_app,"See that nice message field over there? Good. Now fill it in.", 'Message Not Present');
                else
                [app.mx_result, app.mx_remainder] = codeCRC(app.gpb,app.msg_bin);
                [~, app.expected_length] = decodeCRC(app.gpb,app.mx_result);
                app.EncodedmessageTextArea.Value = cell2gui(app.mx_result,'%i');
                app.EncodedmessageTextArea_2.Value = cell2gui(app.mx_result,'%i');
                app.RemainderTextArea.Value = cell2gui(app.mx_remainder,'%i');
                app.format = '%i';
                
                
%                 assignin('base','mx_result',app.mx_result) %debug
%                 assignin('base','mx_remainder',app.mx_remainder) %debug
                assignin('base','expected_length',app.expected_length); %debug
                end
            elseif ~any(app.genpol)
                uialert(app.CRC_app,"It is of utmost importance for the generator polynomial to be defined if we are to get anywhere!", 'Generator Polynomial Empty');
            end
        end

        % Value changed function: EncodedmessageTextArea
        function EncodedmessageTextAreaValueChanged(app, event)
%             value = app.EncodedmessageTextArea.Value;
            
        end

        % Value changed function: RemainderTextArea
        function RemainderTextAreaValueChanged(app, event)
%             value = app.RemainderTextArea.Value;
            
        end

        % Button pushed function: CRC32EthernetButton
        function CRC32EthernetButtonPushed(app, event)
            app.x32Button.Value = 1;
            x32ButtonValueChanged(app);
            app.x31Button.Value = 0;
            x31ButtonValueChanged(app);
            app.x30Button.Value = 0;
            x30ButtonValueChanged(app);
            app.x29Button.Value = 0;
            x29ButtonValueChanged(app);
            app.x28Button.Value = 0;
            x28ButtonValueChanged(app);
            app.x27Button.Value = 0;
            x27ButtonValueChanged(app);
            app.x26Button.Value = 1;
            x26ButtonValueChanged(app);
            app.x25Button.Value = 0;
            x25ButtonValueChanged(app);
            app.x24Button.Value = 0;
            x24ButtonValueChanged(app);
            app.x23Button.Value = 1;
            x23ButtonValueChanged(app);
            app.x22Button.Value = 1;
            x22ButtonValueChanged(app);
            app.x21Button.Value = 0;
            x21ButtonValueChanged(app);
            app.x20Button.Value = 0;
            x20ButtonValueChanged(app);
            app.x19Button.Value = 0;
            x19ButtonValueChanged(app);
            app.x18Button.Value = 0;
            x18ButtonValueChanged(app);
            app.x17Button.Value = 0;
            x17ButtonValueChanged(app);
            app.x16Button.Value = 1;
            x16ButtonValueChanged(app);
            app.x15Button.Value = 0;
            x15ButtonValueChanged(app);
            app.x14Button.Value = 0;
            x14ButtonValueChanged(app);
            app.x13Button.Value = 0;
            x13ButtonValueChanged(app);
            app.x12Button.Value = 1;
            x12ButtonValueChanged(app);
            app.x11Button.Value = 1;
            x11ButtonValueChanged(app);
            app.x10Button.Value = 1;
            x10ButtonValueChanged(app);
            app.x9Button.Value = 0;
            x9ButtonValueChanged(app);
            app.x8Button.Value = 1;
            x8ButtonValueChanged(app);
            app.x7Button.Value = 1;
            x7ButtonValueChanged(app);
            app.x6Button.Value = 0;
            x6ButtonValueChanged(app);
            app.x5Button.Value = 1;
            x5ButtonValueChanged(app);
            app.x4Button.Value = 1;
            x4ButtonValueChanged(app);
            app.x3Button.Value = 0;
            x3ButtonValueChanged(app);
            app.x2Button.Value = 1;
            x2ButtonValueChanged(app);
            app.xButton.Value = 1;
            xButtonValueChanged(app);
            app.Button.Value = 1;
            ButtonValueChanged(app);
            ApplyButtonPushed(app);
        end

        % Button pushed function: CRC16USBDataButton
        function CRC16USBDataButtonPushed(app, event)
            app.x32Button.Value = 0;
            x32ButtonValueChanged(app);
            app.x31Button.Value = 0;
            x31ButtonValueChanged(app);
            app.x30Button.Value = 0;
            x30ButtonValueChanged(app);
            app.x29Button.Value = 0;
            x29ButtonValueChanged(app);
            app.x28Button.Value = 0;
            x28ButtonValueChanged(app);
            app.x27Button.Value = 0;
            x27ButtonValueChanged(app);
            app.x26Button.Value = 0;
            x26ButtonValueChanged(app);
            app.x25Button.Value = 0;
            x25ButtonValueChanged(app);
            app.x24Button.Value = 0;
            x24ButtonValueChanged(app);
            app.x23Button.Value = 0;
            x23ButtonValueChanged(app);
            app.x22Button.Value = 0;
            x22ButtonValueChanged(app);
            app.x21Button.Value = 0;
            x21ButtonValueChanged(app);
            app.x20Button.Value = 0;
            x20ButtonValueChanged(app);
            app.x19Button.Value = 0;
            x19ButtonValueChanged(app);
            app.x18Button.Value = 0;
            x18ButtonValueChanged(app);
            app.x17Button.Value = 0;
            x17ButtonValueChanged(app);
            app.x16Button.Value = 1;
            x16ButtonValueChanged(app);
            app.x15Button.Value = 1;
            x15ButtonValueChanged(app);
            app.x14Button.Value = 0;
            x14ButtonValueChanged(app);
            app.x13Button.Value = 0;
            x13ButtonValueChanged(app);
            app.x12Button.Value = 0;
            x12ButtonValueChanged(app);
            app.x11Button.Value = 0;
            x11ButtonValueChanged(app);
            app.x10Button.Value = 0;
            x10ButtonValueChanged(app);
            app.x9Button.Value = 0;
            x9ButtonValueChanged(app);
            app.x8Button.Value = 0;
            x8ButtonValueChanged(app);
            app.x7Button.Value = 0;
            x7ButtonValueChanged(app);
            app.x6Button.Value = 0;
            x6ButtonValueChanged(app);
            app.x5Button.Value = 0;
            x5ButtonValueChanged(app);
            app.x4Button.Value = 0;
            x4ButtonValueChanged(app);
            app.x3Button.Value = 0;
            x3ButtonValueChanged(app);
            app.x2Button.Value = 1;
            x2ButtonValueChanged(app);
            app.xButton.Value = 0;
            xButtonValueChanged(app);
            app.Button.Value = 1;
            ButtonValueChanged(app);
            ApplyButtonPushed(app);
        end

        % Button pushed function: CRC6X25Button
        function CRC6X25ButtonPushed(app, event)
            app.x32Button.Value = 0;
            x32ButtonValueChanged(app);
            app.x31Button.Value = 0;
            x31ButtonValueChanged(app);
            app.x30Button.Value = 0;
            x30ButtonValueChanged(app);
            app.x29Button.Value = 0;
            x29ButtonValueChanged(app);
            app.x28Button.Value = 0;
            x28ButtonValueChanged(app);
            app.x27Button.Value = 0;
            x27ButtonValueChanged(app);
            app.x26Button.Value = 0;
            x26ButtonValueChanged(app);
            app.x25Button.Value = 0;
            x25ButtonValueChanged(app);
            app.x24Button.Value = 0;
            x24ButtonValueChanged(app);
            app.x23Button.Value = 0;
            x23ButtonValueChanged(app);
            app.x22Button.Value = 0;
            x22ButtonValueChanged(app);
            app.x21Button.Value = 0;
            x21ButtonValueChanged(app);
            app.x20Button.Value = 0;
            x20ButtonValueChanged(app);
            app.x19Button.Value = 0;
            x19ButtonValueChanged(app);
            app.x18Button.Value = 0;
            x18ButtonValueChanged(app);
            app.x17Button.Value = 0;
            x17ButtonValueChanged(app);
            app.x16Button.Value = 1;
            x16ButtonValueChanged(app);
            app.x15Button.Value = 0;
            x15ButtonValueChanged(app);
            app.x14Button.Value = 0;
            x14ButtonValueChanged(app);
            app.x13Button.Value = 0;
            x13ButtonValueChanged(app);
            app.x12Button.Value = 1;
            x12ButtonValueChanged(app);
            app.x11Button.Value = 0;
            x11ButtonValueChanged(app);
            app.x10Button.Value = 0;
            x10ButtonValueChanged(app);
            app.x9Button.Value = 0;
            x9ButtonValueChanged(app);
            app.x8Button.Value = 0;
            x8ButtonValueChanged(app);
            app.x7Button.Value = 0;
            x7ButtonValueChanged(app);
            app.x6Button.Value = 0;
            x6ButtonValueChanged(app);
            app.x5Button.Value = 1;
            x5ButtonValueChanged(app);
            app.x4Button.Value = 0;
            x4ButtonValueChanged(app);
            app.x3Button.Value = 0;
            x3ButtonValueChanged(app);
            app.x2Button.Value = 0;
            x2ButtonValueChanged(app);
            app.xButton.Value = 0;
            xButtonValueChanged(app);
            app.Button.Value = 1;
            ButtonValueChanged(app);
            ApplyButtonPushed(app);
        end

        % Button pushed function: CRC10Button
        function CRC10ButtonPushed(app, event)
            app.x32Button.Value = 0;
            x32ButtonValueChanged(app);
            app.x31Button.Value = 0;
            x31ButtonValueChanged(app);
            app.x30Button.Value = 0;
            x30ButtonValueChanged(app);
            app.x29Button.Value = 0;
            x29ButtonValueChanged(app);
            app.x28Button.Value = 0;
            x28ButtonValueChanged(app);
            app.x27Button.Value = 0;
            x27ButtonValueChanged(app);
            app.x26Button.Value = 0;
            x26ButtonValueChanged(app);
            app.x25Button.Value = 0;
            x25ButtonValueChanged(app);
            app.x24Button.Value = 0;
            x24ButtonValueChanged(app);
            app.x23Button.Value = 0;
            x23ButtonValueChanged(app);
            app.x22Button.Value = 0;
            x22ButtonValueChanged(app);
            app.x21Button.Value = 0;
            x21ButtonValueChanged(app);
            app.x20Button.Value = 0;
            x20ButtonValueChanged(app);
            app.x19Button.Value = 0;
            x19ButtonValueChanged(app);
            app.x18Button.Value = 0;
            x18ButtonValueChanged(app);
            app.x17Button.Value = 0;
            x17ButtonValueChanged(app);
            app.x16Button.Value = 0;
            x16ButtonValueChanged(app);
            app.x15Button.Value = 0;
            x15ButtonValueChanged(app);
            app.x14Button.Value = 0;
            x14ButtonValueChanged(app);
            app.x13Button.Value = 0;
            x13ButtonValueChanged(app);
            app.x12Button.Value = 0;
            x12ButtonValueChanged(app);
            app.x11Button.Value = 0;
            x11ButtonValueChanged(app);
            app.x10Button.Value = 1;
            x10ButtonValueChanged(app);
            app.x9Button.Value = 1;
            x9ButtonValueChanged(app);
            app.x8Button.Value = 0;
            x8ButtonValueChanged(app);
            app.x7Button.Value = 0;
            x7ButtonValueChanged(app);
            app.x6Button.Value = 0;
            x6ButtonValueChanged(app);
            app.x5Button.Value = 1;
            x5ButtonValueChanged(app);
            app.x4Button.Value = 1;
            x4ButtonValueChanged(app);
            app.x3Button.Value = 0;
            x3ButtonValueChanged(app);
            app.x2Button.Value = 0;
            x2ButtonValueChanged(app);
            app.xButton.Value = 1;
            xButtonValueChanged(app);
            app.Button.Value = 1;
            ButtonValueChanged(app);
            ApplyButtonPushed(app);
        end

        % Button pushed function: CRC8ATMHECButton
        function CRC8ATMHECButtonPushed(app, event)
            app.x32Button.Value = 0;
            x32ButtonValueChanged(app);
            app.x31Button.Value = 0;
            x31ButtonValueChanged(app);
            app.x30Button.Value = 0;
            x30ButtonValueChanged(app);
            app.x29Button.Value = 0;
            x29ButtonValueChanged(app);
            app.x28Button.Value = 0;
            x28ButtonValueChanged(app);
            app.x27Button.Value = 0;
            x27ButtonValueChanged(app);
            app.x26Button.Value = 0;
            x26ButtonValueChanged(app);
            app.x25Button.Value = 0;
            x25ButtonValueChanged(app);
            app.x24Button.Value = 0;
            x24ButtonValueChanged(app);
            app.x23Button.Value = 0;
            x23ButtonValueChanged(app);
            app.x22Button.Value = 0;
            x22ButtonValueChanged(app);
            app.x21Button.Value = 0;
            x21ButtonValueChanged(app);
            app.x20Button.Value = 0;
            x20ButtonValueChanged(app);
            app.x19Button.Value = 0;
            x19ButtonValueChanged(app);
            app.x18Button.Value = 0;
            x18ButtonValueChanged(app);
            app.x17Button.Value = 0;
            x17ButtonValueChanged(app);
            app.x16Button.Value = 0;
            x16ButtonValueChanged(app);
            app.x15Button.Value = 0;
            x15ButtonValueChanged(app);
            app.x14Button.Value = 0;
            x14ButtonValueChanged(app);
            app.x13Button.Value = 0;
            x13ButtonValueChanged(app);
            app.x12Button.Value = 0;
            x12ButtonValueChanged(app);
            app.x11Button.Value = 0;
            x11ButtonValueChanged(app);
            app.x10Button.Value = 0;
            x10ButtonValueChanged(app);
            app.x9Button.Value = 0;
            x9ButtonValueChanged(app);
            app.x8Button.Value = 1;
            x8ButtonValueChanged(app);
            app.x7Button.Value = 0;
            x7ButtonValueChanged(app);
            app.x6Button.Value = 0;
            x6ButtonValueChanged(app);
            app.x5Button.Value = 0;
            x5ButtonValueChanged(app);
            app.x4Button.Value = 0;
            x4ButtonValueChanged(app);
            app.x3Button.Value = 0;
            x3ButtonValueChanged(app);
            app.x2Button.Value = 1;
            x2ButtonValueChanged(app);
            app.xButton.Value = 1;
            xButtonValueChanged(app);
            app.Button.Value = 1;
            ButtonValueChanged(app);
            ApplyButtonPushed(app);
        end

        % Button pushed function: CRC5USBTokenButton
        function CRC5USBTokenButtonPushed(app, event)
            app.x32Button.Value = 0;
            x32ButtonValueChanged(app);
            app.x31Button.Value = 0;
            x31ButtonValueChanged(app);
            app.x30Button.Value = 0;
            x30ButtonValueChanged(app);
            app.x29Button.Value = 0;
            x29ButtonValueChanged(app);
            app.x28Button.Value = 0;
            x28ButtonValueChanged(app);
            app.x27Button.Value = 0;
            x27ButtonValueChanged(app);
            app.x26Button.Value = 0;
            x26ButtonValueChanged(app);
            app.x25Button.Value = 0;
            x25ButtonValueChanged(app);
            app.x24Button.Value = 0;
            x24ButtonValueChanged(app);
            app.x23Button.Value = 0;
            x23ButtonValueChanged(app);
            app.x22Button.Value = 0;
            x22ButtonValueChanged(app);
            app.x21Button.Value = 0;
            x21ButtonValueChanged(app);
            app.x20Button.Value = 0;
            x20ButtonValueChanged(app);
            app.x19Button.Value = 0;
            x19ButtonValueChanged(app);
            app.x18Button.Value = 0;
            x18ButtonValueChanged(app);
            app.x17Button.Value = 0;
            x17ButtonValueChanged(app);
            app.x16Button.Value = 0;
            x16ButtonValueChanged(app);
            app.x15Button.Value = 0;
            x15ButtonValueChanged(app);
            app.x14Button.Value = 0;
            x14ButtonValueChanged(app);
            app.x13Button.Value = 0;
            x13ButtonValueChanged(app);
            app.x12Button.Value = 0;
            x12ButtonValueChanged(app);
            app.x11Button.Value = 0;
            x11ButtonValueChanged(app);
            app.x10Button.Value = 0;
            x10ButtonValueChanged(app);
            app.x9Button.Value = 0;
            x9ButtonValueChanged(app);
            app.x8Button.Value = 0;
            x8ButtonValueChanged(app);
            app.x7Button.Value = 0;
            x7ButtonValueChanged(app);
            app.x6Button.Value = 0;
            x6ButtonValueChanged(app);
            app.x5Button.Value = 1;
            x5ButtonValueChanged(app);
            app.x4Button.Value = 0;
            x4ButtonValueChanged(app);
            app.x3Button.Value = 0;
            x3ButtonValueChanged(app);
            app.x2Button.Value = 1;
            x2ButtonValueChanged(app);
            app.xButton.Value = 0;
            xButtonValueChanged(app);
            app.Button.Value = 1;
            ButtonValueChanged(app);
            ApplyButtonPushed(app);
        end

        % Value changed function: EncodedmessageTextArea_2
        function EncodedmessageTextArea_2ValueChanged(app, event)
            value = app.EncodedmessageTextArea_2.Value;
            mx = value;
            velikost = size(mx);
            mx = reshape(mx,[velikost(2) velikost(1)]);
            app.mx_result = mx;
            app.format = '%s';
%             assignin('base','mx_test',mx); %debug
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            if any(app.genpol)
                if isempty(app.MessageEditField.Value)
                    uialert(app.CRC_app,"See that nice message field over there? Good. Now fill it in.", 'Message Not Present');
                elseif isempty(app.EncodedmessageTextArea_2.Value)
                else
                [app.mx_decoded, app.actual_length] = decodeCRC(app.gpb,app.mx_result);
                app.DecodedTextArea.Value = cell2gui(app.mx_decoded,'%i');
                assignin('base','actual_length',app.actual_length); %debug
                
                [app.chyba, chybastr] = errdetect(app.mx_decoded,app.actual_length,app.expected_length);

                if app.chyba ~= 0
                    chybhlaska = strcat("A byte on line:", {' '}, chybastr, {' '}, "did not pass the validation. Try harder.");
                    uialert(app.CRC_app,chybhlaska, 'Error during transmission','Icon','warning');
                    set(app.RemovechecksumButton, 'Enable', 'off');
                else
                    uialert(app.CRC_app,"It seems that all bytes are being transmitted successfully... For now.", 'Validated successfully','Icon','success');
                    set(app.RemovechecksumButton, 'Enable', 'on');
                end
                
                assignin('base','mx_decoded',app.mx_decoded) %debug
                assignin('base', 'chyba',app.chyba) %debug
                end
            elseif ~any(app.genpol)
                uialert(app.CRC_app,"It is of utmost importance for the generator polynomial to be defined if we are to get anywhere!", 'Generator Polynomial Empty');
            end
        end

        % Button pushed function: RemovechecksumButton
        function RemovechecksumButtonPushed(app, event)
            if app.chyba == 0
                mx_final = removechcksm(app.mx_result, app.gpb);
%                 app.DecodedTextArea_2.Value = cell2gui(mx_final,'%i');
                app.DecodedTextArea_2.Value = cell2gui(mx_final,app.format);
            end
        end

        % Value changed function: DecodedTextArea_2
        function DecodedTextArea_2ValueChanged(app, event)
%             value = app.DecodedTextArea_2.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRC_app = uifigure('Visible', 'off');
            app.CRC_app.Name = 'CRC';
            app.TabGroup = uitabgroup(app.CRC_app);
            app.GeneratorTab = uitab(app.TabGroup);
            app.GeneratorTab.Title = 'Generator';
            app.GeneratorpolynomialPanel = uipanel(app.GeneratorTab);
            app.GeneratorpolynomialPanel.Title = 'Generator polynomial';
            app.xButton = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.xButton.ValueChangedFcn = createCallbackFcn(app, @xButtonValueChanged, true);
            app.xButton.Text = 'x';
            app.x2Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x2Button.ValueChangedFcn = createCallbackFcn(app, @x2ButtonValueChanged, true);
            app.x2Button.Text = 'x^2';
            app.x3Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x3Button.ValueChangedFcn = createCallbackFcn(app, @x3ButtonValueChanged, true);
            app.x3Button.Text = 'x^3';
            app.x4Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x4Button.ValueChangedFcn = createCallbackFcn(app, @x4ButtonValueChanged, true);
            app.x4Button.Text = 'x^4';
            app.x5Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x5Button.ValueChangedFcn = createCallbackFcn(app, @x5ButtonValueChanged, true);
            app.x5Button.Text = 'x^5';
            app.x6Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x6Button.ValueChangedFcn = createCallbackFcn(app, @x6ButtonValueChanged, true);
            app.x6Button.Text = 'x^6';
            app.x7Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x7Button.ValueChangedFcn = createCallbackFcn(app, @x7ButtonValueChanged, true);
            app.x7Button.Text = 'x^7';
            app.x8Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x8Button.ValueChangedFcn = createCallbackFcn(app, @x8ButtonValueChanged, true);
            app.x8Button.Text = 'x^8';
            app.x9Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x9Button.ValueChangedFcn = createCallbackFcn(app, @x9ButtonValueChanged, true);
            app.x9Button.Text = 'x^9';
            app.x10Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x10Button.ValueChangedFcn = createCallbackFcn(app, @x10ButtonValueChanged, true);
            app.x10Button.Text = 'x^10';
            app.x11Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x11Button.ValueChangedFcn = createCallbackFcn(app, @x11ButtonValueChanged, true);
            app.x11Button.Text = 'x^11';
            app.x12Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x12Button.ValueChangedFcn = createCallbackFcn(app, @x12ButtonValueChanged, true);
            app.x12Button.Text = 'x^12';
            app.x13Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x13Button.ValueChangedFcn = createCallbackFcn(app, @x13ButtonValueChanged, true);
            app.x13Button.Text = 'x^13';
            app.x14Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x14Button.ValueChangedFcn = createCallbackFcn(app, @x14ButtonValueChanged, true);
            app.x14Button.Text = 'x^14';
            app.x15Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x15Button.ValueChangedFcn = createCallbackFcn(app, @x15ButtonValueChanged, true);
            app.x15Button.Text = 'x^15';
            app.x16Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x16Button.ValueChangedFcn = createCallbackFcn(app, @x16ButtonValueChanged, true);
            app.x16Button.Text = 'x^16';
            app.x17Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x17Button.ValueChangedFcn = createCallbackFcn(app, @x17ButtonValueChanged, true);
            app.x17Button.Text = 'x^17';
            app.x18Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x18Button.ValueChangedFcn = createCallbackFcn(app, @x18ButtonValueChanged, true);
            app.x18Button.Text = 'x^18';
            app.x19Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x19Button.ValueChangedFcn = createCallbackFcn(app, @x19ButtonValueChanged, true);
            app.x19Button.Text = 'x^19';
            app.x20Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x20Button.ValueChangedFcn = createCallbackFcn(app, @x20ButtonValueChanged, true);
            app.x20Button.Text = 'x^20';
            app.x21Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x21Button.ValueChangedFcn = createCallbackFcn(app, @x21ButtonValueChanged, true);
            app.x21Button.Text = 'x^21';
            app.x22Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x22Button.ValueChangedFcn = createCallbackFcn(app, @x22ButtonValueChanged, true);
            app.x22Button.Text = 'x^22';
            app.x23Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x23Button.ValueChangedFcn = createCallbackFcn(app, @x23ButtonValueChanged, true);
            app.x23Button.Text = 'x^23';
            app.x24Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x24Button.ValueChangedFcn = createCallbackFcn(app, @x24ButtonValueChanged, true);
            app.x24Button.Text = 'x^24';
            app.x25Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x25Button.ValueChangedFcn = createCallbackFcn(app, @x25ButtonValueChanged, true);
            app.x25Button.Text = 'x^25';
            app.x26Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x26Button.ValueChangedFcn = createCallbackFcn(app, @x26ButtonValueChanged, true);
            app.x26Button.Text = 'x^26';
            app.x27Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x27Button.ValueChangedFcn = createCallbackFcn(app, @x27ButtonValueChanged, true);
            app.x27Button.Text = 'x^27';
            app.x28Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x28Button.ValueChangedFcn = createCallbackFcn(app, @x28ButtonValueChanged, true);
            app.x28Button.Text = 'x^28';
            app.x29Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x29Button.ValueChangedFcn = createCallbackFcn(app, @x29ButtonValueChanged, true);
            app.x29Button.Text = 'x^29';
            app.x30Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x30Button.ValueChangedFcn = createCallbackFcn(app, @x30ButtonValueChanged, true);
            app.x30Button.Text = 'x^30';
            app.x31Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x31Button.ValueChangedFcn = createCallbackFcn(app, @x31ButtonValueChanged, true);
            app.x31Button.Text = 'x^31';
            app.x32Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.x32Button.ValueChangedFcn = createCallbackFcn(app, @x32ButtonValueChanged, true);
            app.x32Button.Text = 'x^32';
            app.ApplyButton = uibutton(app.GeneratorpolynomialPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.Button = uibutton(app.GeneratorpolynomialPanel, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '1';
            app.gxTextArea = uitextarea(app.GeneratorTab);
            app.gxTextArea.ValueChangedFcn = createCallbackFcn(app, @gxTextAreaValueChanged, true);
            app.gxTextArea.Editable = 'off';
            app.PresetsPanel = uipanel(app.GeneratorTab);
            app.PresetsPanel.Title = 'Presets';
            app.CRC32EthernetButton = uibutton(app.PresetsPanel, 'push');
            app.CRC32EthernetButton.ButtonPushedFcn = createCallbackFcn(app, @CRC32EthernetButtonPushed, true);
            app.CRC32EthernetButton.Tooltip = {'Ethernet, MPEG-2, PKZIP, Gzip, Bzip2 and PNG'};
            app.CRC32EthernetButton.Text = 'CRC32 Ethernet';
            app.CRC6X25Button = uibutton(app.PresetsPanel, 'push');
            app.CRC6X25Button.ButtonPushedFcn = createCallbackFcn(app, @CRC6X25ButtonPushed, true);
            app.CRC6X25Button.Text = 'CRC6 X25';
            app.CRC10Button = uibutton(app.PresetsPanel, 'push');
            app.CRC10Button.ButtonPushedFcn = createCallbackFcn(app, @CRC10ButtonPushed, true);
            app.CRC10Button.Text = 'CRC10';
            app.CRC8ATMHECButton = uibutton(app.PresetsPanel, 'push');
            app.CRC8ATMHECButton.ButtonPushedFcn = createCallbackFcn(app, @CRC8ATMHECButtonPushed, true);
            app.CRC8ATMHECButton.Text = 'CRC8  ATM HEC';
            app.CRC16USBDataButton = uibutton(app.PresetsPanel, 'push');
            app.CRC16USBDataButton.ButtonPushedFcn = createCallbackFcn(app, @CRC16USBDataButtonPushed, true);
            app.CRC16USBDataButton.Text = 'CRC16 USB Data';
            app.CRC5USBTokenButton = uibutton(app.PresetsPanel, 'push');
            app.CRC5USBTokenButton.ButtonPushedFcn = createCallbackFcn(app, @CRC5USBTokenButtonPushed, true);
            app.CRC5USBTokenButton.Text = 'CRC5 USB Token';
            app.gxTextArea_2 = uitextarea(app.GeneratorTab);
            app.gxTextArea_2.Editable = 'off';
            app.gxTextAreaLabel = uilabel(app.GeneratorTab);
            app.gxTextAreaLabel.Text = 'Generator polynomial: ';
            app.gxTextArea_2Label = uilabel(app.GeneratorTab);
            app.gxTextArea_2Label.Text = 'g(x): ';
            app.EncodeTab = uitab(app.TabGroup);
            app.EncodeTab.Title = 'Encode';
            app.mxTextArea = uitextarea(app.EncodeTab);
            app.mxTextArea.ValueChangedFcn = createCallbackFcn(app, @mxTextAreaValueChanged, true);
            app.mxTextArea.Editable = 'off';
            app.MessageEditField = uieditfield(app.EncodeTab, 'text');
            app.MessageEditField.ValueChangedFcn = createCallbackFcn(app, @MessageEditFieldValueChanged, true);
            app.MessageEditField.Enable = 'off';
            app.EncodeButton = uibutton(app.EncodeTab, 'push');
            app.EncodeButton.ButtonPushedFcn = createCallbackFcn(app, @EncodeButtonPushed, true);
            app.EncodeButton.Enable = 'off';
            app.EncodeButton.Text = 'Encode';
            app.RemainderTextArea = uitextarea(app.EncodeTab);
            app.RemainderTextArea.ValueChangedFcn = createCallbackFcn(app, @RemainderTextAreaValueChanged, true);
            app.RemainderTextArea.Editable = 'off';
            app.EncodedmessageTextArea = uitextarea(app.EncodeTab);
            app.EncodedmessageTextArea.ValueChangedFcn = createCallbackFcn(app, @EncodedmessageTextAreaValueChanged, true);
            app.EncodedmessageTextArea.Editable = 'off';
            app.MessageLabel = uilabel(app.EncodeTab);
            app.MessageLabel.Text = 'Message:';
            app.RemainderTextAreaLabel = uilabel(app.EncodeTab);
            app.RemainderTextAreaLabel.Text = 'Remainder: ';
            app.EncodedmessageTextAreaLabel = uilabel(app.EncodeTab);
            app.EncodedmessageTextAreaLabel.Text = 'Encoded message: ';
            app.DecodeTab = uitab(app.TabGroup);
            app.DecodeTab.Title = 'Decode';
            app.EncodedmessageTextArea_2 = uitextarea(app.DecodeTab);
            app.EncodedmessageTextArea_2.ValueChangedFcn = createCallbackFcn(app, @EncodedmessageTextArea_2ValueChanged, true);
            app.EncodedmessageTextArea_2.Tooltip = {'Zpráva lze upravit pro simulování chyb.'};
            app.CheckButton = uibutton(app.DecodeTab, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.DecodedTextArea = uitextarea(app.DecodeTab);
            app.DecodedTextArea.Editable = 'off';
            app.EncodedmessageTextArea_2Label = uilabel(app.DecodeTab);
            app.EncodedmessageTextArea_2Label.Text = 'Encoded message: ';
            app.EncodedmessageTextArea_2Label_2 = uilabel(app.DecodeTab);
            app.EncodedmessageTextArea_2Label_2.Text = 'Errors: ';
            app.DecodedTextArea_2 = uitextarea(app.DecodeTab);
            app.DecodedTextArea_2.ValueChangedFcn = createCallbackFcn(app, @DecodedTextArea_2ValueChanged, true);
            app.DecodedTextArea_2.Editable = 'off';
            app.RemovechecksumButton = uibutton(app.DecodeTab, 'push');
            app.RemovechecksumButton.ButtonPushedFcn = createCallbackFcn(app, @RemovechecksumButtonPushed, true);
            app.RemovechecksumButton.Enable = 'off';
            app.RemovechecksumButton.Text = 'Remove checksum';
            app.ReceivedMessageLabel = uilabel(app.DecodeTab);
            app.ReceivedMessageLabel.Text = 'Received Message: ';
            % Show the figure after all components are created
            app.CRC_app.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CRC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRC_app)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRC_app)
        end
    end
end